classdef Citynet4_7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % 城市网络
        UIAxes                matlab.ui.control.UIAxes           % 网络示意图
        Button2               matlab.ui.control.Button           % P2矩阵
        Button3               matlab.ui.control.Button           % 开始
        Label                 matlab.ui.control.Label            % made by ...
        Label2                matlab.ui.control.Label            % 请输入城市数目和...
        Button                matlab.ui.control.Button           % P1矩阵
        Label3                matlab.ui.control.Label            % 说明：红色为冗余...
        LabelNumericEditField matlab.ui.control.Label            % 城市数目
        NumericEditField      matlab.ui.control.NumericEditField % [2 100]
        Label4                matlab.ui.control.Label            % 线路数目
        NumericEditField3     matlab.ui.control.NumericEditField % [1 1000]
        Label5                matlab.ui.control.Label            %  提示信息
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button button pushed function
        function p1Print(app)
        A=load('C:\Users\geoganlle\Desktop\citynet\bin\Release\p1fh.txt');
        B=load('C:\Users\geoganlle\Desktop\citynet\bin\Release\undata.txt');
        [~,n]=size(A);
        w=floor(sqrt(n));h=floor(n/w);        
        x=[];y=[];
        for i=1:h      
            for j=1:w
                x=[x 10*rand(1)+(j-1)*10];
                y=[y 10*rand(1)+(i-1)*10];
            end
        end
        ed=n-h*w;
        for i=1:ed
           x=[x 10*rand(1)+(i-1)*10]; 
           y=[y 10*rand(1)+h*10];
        end
        plot(app.UIAxes,x,y,'black.');    
        title(app.UIAxes,'P1'); 
        for i=1:n
            for j=i:n
                if A(i,j)~=0
                    c=num2str(A(i,j)); 
                    line(app.UIAxes,[x(i) x(j)],[y(i) y(j)],'color','black');
                end
                if A(i,i)==1
                    text(app.UIAxes,x(i),y(i),num2str(i),'Fontsize',25,'color','g');
                else
                    text(app.UIAxes,x(i),y(i),num2str(i),'Fontsize',25,'color','black');
                end
                if B(i,j)==1
                    line(app.UIAxes,[x(i) x(j)],[y(i) y(j)],'color','red');
                end
            end
        end 
        end

        % Button3 button pushed function
        function startexe(app)
            open('C:\Users\geoganlle\Desktop\citynet\bin\Release\CityNet.exe');
        end

        % Button2 button pushed function
        function p2Print(app)
        C=load('C:\Users\geoganlle\Desktop\citynet\bin\Release\p2fh.txt');
        D=load('C:\Users\geoganlle\Desktop\citynet\bin\Release\secure.txt');
        [~,n]=size(C);
        w=floor(sqrt(n));       
        h=floor(n/w);        
        x=[];
        y=[];
        for i=1:h      
            for j=1:w
                x=[x 10*rand(1)+(j-1)*10];
                y=[y 10*rand(1)+(i-1)*10];
            end
        end
        ed=n-h*w;
        for i=1:ed
           x=[x 10*rand(1)+(i-1)*10]; 
           y=[y 10*rand(1)+h*10];
        end
        plot(app.UIAxes,x,y,'black.');    
        
        title(app.UIAxes,'P2'); 
        for i=1:n
            for j=i:n
                if C(i,j)~=0
                    c=num2str(C(i,j)); 
                    %text((x(i)+x(j))/2,(y(i)+y(j))/2,c,'Fontsize',10); 
                    line(app.UIAxes,[x(i) x(j)],[y(i) y(j)],'color','black');
                end
                %text(app.UIAxes,x(i),y(i),num2str(i),'Fontsize',25,'color','black');             
            
                if C(i,i)==1
                    text(app.UIAxes,x(i),y(i),num2str(i),'Fontsize',25,'color','g');
                else
                    text(app.UIAxes,x(i),y(i),num2str(i),'Fontsize',25,'color','black');
                end
                if D(i,j)==1
                    line(app.UIAxes,[x(i) x(j)],[y(i) y(j)],'color','green');
                end
            end
        end
        end

        % NumericEditField value changed function
        function cityNum(app)
            citynum = app.NumericEditField.Value;
            delete('C:\Users\geoganlle\Desktop\citynet\bin\Release\citycin.txt'); 
            fcin = fopen('C:\Users\geoganlle\Desktop\citynet\bin\Release\citycin.txt','w+');
            fprintf(fcin,'%d',citynum);
            fclose(fcin);
        end

        % NumericEditField3 value changed function
        function lineNum(app)
            linenum = app.NumericEditField3.Value;
            citynum = app.NumericEditField.Value;
            rightnum = citynum*(citynum-1)/2;
            if linenum<(citynum-1)
                app.Label5.Text = '输入线路数目过小，请重新输入';
                app.Label5.FontColor = [1 0 0];
            else
                if linenum>rightnum
                    app.Label5.Text = '输入线路数目过大，请重新输入';
                    app.Label5.FontColor = [1 0 0];
                else
                    app.Label5.Text = '输入合法，点击开始按钮完成初始化';
                    app.Label5.FontColor = [0 1 0];
                delete('C:\Users\geoganlle\Desktop\citynet\bin\Release\linecin.txt'); 
                fcin = fopen('C:\Users\geoganlle\Desktop\citynet\bin\Release\linecin.txt','w+');
                fprintf(fcin,'%d',linenum);
                fclose(fcin);
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '城市网络';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '网络示意图');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @p2Print);
            app.Button2.Text = 'P2矩阵';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @startexe);
            app.Button3.Text = '开始';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'made by 顾振刚  李祥瑞';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = '请输入城市数目和线路数目';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @p1Print);
            app.Button.Text = 'P1矩阵';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = '说明：红色为冗余，绿色为重要';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = '城市数目';
            app.NumericEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @cityNum);
            app.NumericEditField.Limits = [2 100];
            app.NumericEditField.Value = 2;
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = '线路数目';
            app.NumericEditField3 = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField3.ValueChangedFcn = createCallbackFcn(app, @lineNum);
            app.NumericEditField3.Limits = [1 1000];
            app.NumericEditField3.Value = 1;
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = ' 提示信息';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Citynet4_7()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end